classdef projektApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        StartButton      matlab.ui.control.Button
        nEditFieldLabel  matlab.ui.control.Label
        nEditField       matlab.ui.control.NumericEditField
        kEditFieldLabel  matlab.ui.control.Label
        kEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            polje = app.UIAxes; 
            cla(polje)
            hold(polje, 'on')
            r = 10;
            n = app.nEditField.Value;
            k = app.kEditField.Value;
            koti = zeros(1, n);
            x = zeros(2, n);
            x(1, 1) = 2 * r;
            x(2, 1) = r;
            kot_premik = 2 * pi / n;
            for i = 2: n
                prej = koti(i - 1);
                koti(i) = prej + kot_premik;
                x(1, i) = cos(koti(i)) * r + r;
                x(2, i) = sin(koti(i)) * r + r;
            end
            if rem(n, 2) == 1
                x = [0, -1; 1, 0] * x;
                x(1, :) = x(1, :) + 2 * r;
            end
            poligon = polyshape(x(1, :), x(2, :)); 

            polje = plot(app.UIAxes, poligon, 'LineWidth', 10);
            polje.FaceColor = 'green';
            
            tocka = rand(2, 1) * 2 * r;
            ali_je_notri = isinterior(poligon, tocka(1), tocka(2));
            
            while ~ali_je_notri
                tocka = rand(2, 1) * r;
                ali_je_notri = isinterior(poligon, tocka(1), tocka(2));
            end
            
            vektorji = zeros(2, n);
            for i = 1: n - 1
                vektorji(1, i) = x(1, i + 1) - x(1, i); 
                vektorji(2, i) = x(2, i + 1) - x(2, i);
            end
            vektorji(1, n) = x(1, 1) - x(1, n); 
            vektorji(2, n) = x(2, 1) - x(2, n);
        
            zeljena_smer = [(x(1, 1) + x(1, 2))/ 2; (x(2, 1) + x(2, 2))/ 2];
            seka = 1; 
            for z = 1: k + 1
                presek = [vektorji(1, seka); vektorji(2, seka)];
                vek = [tocka(1) - zeljena_smer(1); tocka(2) - zeljena_smer(2)];
                k_pre = (tocka(2) - zeljena_smer(2)) / (tocka(1) - zeljena_smer(1));
                yp = @(x) k_pre .* x + tocka(2) - k_pre * tocka(1);

                x1 = linspace(tocka(1), zeljena_smer(1));
                y1 = yp(x1);
                hitrost = floor(norm(tocka - zeljena_smer));
                krogla = plot(app.UIAxes, tocka(1), tocka(2), 'ko', 'MarkerSize', 10, 'MarkerFaceColor', 'w');
                for i = 1: length(x1)
                    set(krogla, 'XData', x1(i), 'YData', y1(i));
                    pause(hitrost * 0.0005);
                end
                if z ~= k + 1
                    delete(krogla);
                end
              
                kot = acos(dot(vek, presek) / (norm(vek) * norm(presek)));
                rezerva = tocka;
                tocka = zeljena_smer;
                if kot < pi / 2
                    kot = pi - 2 * kot; 
                    zeljena_smer(1) = cos(kot) .* vek(1) - sin(kot) .* vek(2);
                    zeljena_smer(2) = sin(kot) * vek(1) + cos(kot) * vek(2);
                else
                    kot = 2 * kot - pi; 
                    zeljena_smer(1) = cos(kot) * vek(1) + sin(kot) * vek(2);
                    zeljena_smer(2) = -sin(kot) * vek(1) + cos(kot) * vek(2);
                end
                zeljena_smer(1) = zeljena_smer(1) + rezerva(1);
                zeljena_smer(2) = zeljena_smer(2) + rezerva(2);
        
                kp_pre = (rezerva(2) - zeljena_smer(2)) / (rezerva(1) - zeljena_smer(1));
                pregledano = 0;
                for j = 1: n - 1
                    if seka ~= j                      
                        kj = vektorji(2, j) / vektorji(1, j);
                        yj = @(p) kj .* p + x(2, j) - kj * x(1, j);
                        presecisce = (tocka(2) - kp_pre * tocka(1) - x(2, j) + kj * x(1, j)) / (kj - kp_pre);
                        prvi = ((presecisce <= x(1, j) && presecisce >= x(1, j + 1) || (presecisce >= x(1, j) && presecisce <= x(1, j + 1))));
                        drugi = ((yj(presecisce) <= x(2, j) && yj(presecisce) >= x(2, j + 1)) || (yj(presecisce) >= x(2, j) && yj(presecisce) <= x(2, j + 1)));
                        if prvi && drugi && ~isnan(presecisce)
                            seka = j; 
                            zeljena_smer(1) = presecisce;
                            zeljena_smer(2) = yj(presecisce);
                            pregledano = 1;  
                            break
                        end
                    end
                end
                if pregledano == 0 
                    kj = vektorji(2, n) / vektorji(1, n);
                    yj = @(p) kj .* p + x(2, n) - kj * x(1, n);
                    presecisce = (tocka(2) - kp_pre * tocka(1) - x(2, n) + kj * x(1, n)) / (kj - kp_pre);
                    seka = n; 
                    zeljena_smer(1) = presecisce;
                    zeljena_smer(2) = yj(presecisce);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Biiljardna miza'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.kEditFieldLabel = uilabel(app.UIFigure);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projektApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end